classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        SavedataButton              matlab.ui.control.StateButton
        InputfileEditField          matlab.ui.control.EditField
        InputfileEditFieldLabel     matlab.ui.control.Label
        Sm3sEditField               matlab.ui.control.NumericEditField
        Sm3sEditFieldLabel          matlab.ui.control.Label
        Vm3EditField                matlab.ui.control.NumericEditField
        Vm3EditFieldLabel           matlab.ui.control.Label
        N_w0EditField               matlab.ui.control.NumericEditField
        N_w0EditFieldLabel          matlab.ui.control.Label
        N_v0EditField               matlab.ui.control.NumericEditField
        N_v0EditFieldLabel          matlab.ui.control.Label
        N_p0EditField               matlab.ui.control.NumericEditField
        N_p0EditFieldLabel          matlab.ui.control.Label
        T_ionsEditField             matlab.ui.control.NumericEditField
        T_ionsEditFieldLabel        matlab.ui.control.Label
        T_releasesEditField         matlab.ui.control.NumericEditField
        T_releasesEditFieldLabel    matlab.ui.control.Label
        T_retentionsEditField       matlab.ui.control.NumericEditField
        T_retentionsEditFieldLabel  matlab.ui.control.Label
        T_psEditField               matlab.ui.control.NumericEditField
        T_psEditFieldLabel          matlab.ui.control.Label
        StartButton                 matlab.ui.control.Button
        FinaltimesEditField         matlab.ui.control.NumericEditField
        Label_3                     matlab.ui.control.Label
        StepsEditField              matlab.ui.control.NumericEditField
        Label_2                     matlab.ui.control.Label
        InitialtimesEditField       matlab.ui.control.NumericEditField
        Label                       matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: InitialtimesEditField
        function InitialtimesEditFieldValueChanged(app, event)
            value = app.InitialtimesEditField.Value;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            t = app.InitialtimesEditField.Value :  app.StepsEditField.Value: app.FinaltimesEditField.Value;
            
            t_p = app.T_psEditField.Value;
            t_retention = app.T_retentionsEditField.Value;
            t_release = app.T_releasesEditField.Value;
            t_ion = app.T_ionsEditField.Value;
            t_pump = app.Vm3EditField.Value / app.Sm3sEditField.Value;
            N_p0 = app.N_p0EditField.Value;
            N_v0 = app.N_v0EditField.Value;
            N_w0 = app.N_w0EditField.Value;
            par = [t_p, t_retention, t_release, t_ion, t_pump, N_p0, N_v0, N_w0];

            tG = readmatrix(app.InputfileEditField.Value);
            G = tG(:, 2);

            ax = app.UIAxes;
            N_p = MATLAB_function_model_new(t, par, G);
            plot(ax, t, N_p);
        end

        % Value changed function: StepsEditField
        function StepsEditFieldValueChanged(app, event)
            value = app.StepsEditField.Value;
        end

        % Value changed function: FinaltimesEditField
        function FinaltimesEditFieldValueChanged(app, event)
            value = app.FinaltimesEditField.Value;
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: SavedataButton
        function SavedataButtonValueChanged(app, event)
            value = app.SavedataButton.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Density responce')
            xlabel(app.UIAxes, 'Time, s')
            ylabel(app.UIAxes, 'Np')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Initial time, s';
            app.InitialtimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialtimesEditField.ValueChangedFcn = createCallbackFcn(app, @InitialtimesEditFieldValueChanged, true);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'Step, s';
            app.StepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsEditField.ValueChangedFcn = createCallbackFcn(app, @StepsEditFieldValueChanged, true);
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = 'Final time, s';
            app.FinaltimesEditField = uieditfield(app.UIFigure, 'numeric');
            app.FinaltimesEditField.ValueChangedFcn = createCallbackFcn(app, @FinaltimesEditFieldValueChanged, true);
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.T_psEditFieldLabel = uilabel(app.UIFigure);
            app.T_psEditFieldLabel.Text = 'T_p, s';
            app.T_psEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_retentionsEditFieldLabel = uilabel(app.UIFigure);
            app.T_retentionsEditFieldLabel.Text = 'T_retention, s';
            app.T_retentionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_releasesEditFieldLabel = uilabel(app.UIFigure);
            app.T_releasesEditFieldLabel.Text = 'T_release, s';
            app.T_releasesEditField = uieditfield(app.UIFigure, 'numeric');
            app.T_ionsEditFieldLabel = uilabel(app.UIFigure);
            app.T_ionsEditFieldLabel.Text = 'T_ion, s';
            app.T_ionsEditField = uieditfield(app.UIFigure, 'numeric');
            app.N_p0EditFieldLabel = uilabel(app.UIFigure);
            app.N_p0EditFieldLabel.Text = 'N_p0';
            app.N_p0EditField = uieditfield(app.UIFigure, 'numeric');
            app.N_v0EditFieldLabel = uilabel(app.UIFigure);
            app.N_v0EditFieldLabel.Text = 'N_v0';
            app.N_v0EditField = uieditfield(app.UIFigure, 'numeric');
            app.N_w0EditFieldLabel = uilabel(app.UIFigure);
            app.N_w0EditFieldLabel.Text = 'N_w0';
            app.N_w0EditField = uieditfield(app.UIFigure, 'numeric');
            app.Vm3EditFieldLabel = uilabel(app.UIFigure);
            app.Vm3EditFieldLabel.Text = 'V, m3';
            app.Vm3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Sm3sEditFieldLabel = uilabel(app.UIFigure);
            app.Sm3sEditFieldLabel.Text = 'S, m3/s';
            app.Sm3sEditField = uieditfield(app.UIFigure, 'numeric');
            app.InputfileEditFieldLabel = uilabel(app.UIFigure);
            app.InputfileEditFieldLabel.Text = 'Input file';
            app.InputfileEditField = uieditfield(app.UIFigure, 'text');
            app.SavedataButton = uibutton(app.UIFigure, 'state');
            app.SavedataButton.ValueChangedFcn = createCallbackFcn(app, @SavedataButtonValueChanged, true);
            app.SavedataButton.Text = 'Save data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end